classdef searchAlgorithm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SearchAlgorithmDropDownLabel  matlab.ui.control.Label
        SearchAlgorithmDropDown       matlab.ui.control.DropDown
        ResolutionSpinnerLabel        matlab.ui.control.Label
        ResolutionSpinner             matlab.ui.control.Spinner
        MapDropDownLabel              matlab.ui.control.Label
        MapDropDown                   matlab.ui.control.DropDown
        StartPositionPanel            matlab.ui.container.Panel
        XLabel_Start                  matlab.ui.control.Label
        XEditField_Start              matlab.ui.control.NumericEditField
        YLabel_Start                  matlab.ui.control.Label
        YEditField_Start              matlab.ui.control.NumericEditField
        GoalPositionPanel             matlab.ui.container.Panel
        XLabel_Goal                   matlab.ui.control.Label
        XEditField_Goal               matlab.ui.control.NumericEditField
        YLabel_Goal                   matlab.ui.control.Label
        YEditField_Goal               matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
        SetPositionsButton            matlab.ui.control.Button
        ActionSpaceDropDownLabel      matlab.ui.control.Label
        ActionSpaceDropDown           matlab.ui.control.DropDown
        DistanceDropDownLabel         matlab.ui.control.Label
        DistanceDropDown              matlab.ui.control.DropDown
        CommentsTextAreaLabel         matlab.ui.control.Label
        CommentsTextArea              matlab.ui.control.TextArea
        RobotTypeDropDownLabel        matlab.ui.control.Label
        RobotTypeDropDown             matlab.ui.control.DropDown
        RobotRadiusSpinnerLabel       matlab.ui.control.Label
        RobotRadiusSpinner            matlab.ui.control.Spinner
        ClearanceSpinnerLabel         matlab.ui.control.Label
        ClearanceSpinner              matlab.ui.control.Spinner
        StartButton                   matlab.ui.control.Button
        BackButton                    matlab.ui.control.Button
        WeightSpinnerLabel            matlab.ui.control.Label
        WeightSpinner                 matlab.ui.control.Spinner
        ReplayButton                  matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        CloseButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        actions = [0 1; 1 0; 0 -1; -1 0];                                       % {[0 1; 1 0; 0 -1; -1 0], [0 1; 1 1; 1 0; 1 -1; 0 -1; -1 -1; -1 0; -1 1]}
        clearance = 0;                                                          % Set when robot is rigid using the respective dialog box
        distance = 'Manhattan';                                                 % {'Manhattan', 'Euclidean', 'Diagonal'}
        goalPos = [250 150];                                                    % Set using the respective dialog box
        map = {};                                                               % Create a cell to store all values related to map - grids, obstacles
        mapName = 'Map 2018';                                                   % {'Map 2018', 'Map 2019'}
        nodes = [];                                                             % An array used to store the expanded nodes
        obsSpace = containers.Map('KeyType', 'double', 'ValueType', 'any');     % A dictionary to store all indices identified as obstacles
        path = [];                                                              % An array used to store the path from start position to goal position
        radius = 0;                                                             % Set when robot is rigid using the respective dialog box
        res = 5;                                                                % Set using the respective dialog box
        searchAlgo = 'BFS';                                                     % {'BFS', 'DFS', 'Dijkstra', 'A*', 'Weighted A*'}
        shape = {};                                                             % Save the values for the shapes - rectangle, concave, circle, ellipse
        startPos = [0 0];                                                       % Set using the respective dialog box
        w = 1;                                                                  % Weight defined for weighted A*
    end
    
    methods (Access = private)
        
        %%%% Plot the actual map
        function plotMap(app)
            % Clear the axes
            cla(app.UIAxes);
            
            % Do not show the grid
            grid(app.UIAxes, 'off')
            
            % Define the boundary margin
            margin = app.radius + app.clearance;
            
            % Switch hold on
            hold(app.UIAxes, 'on');
            
            % Define extension color
            color = [47 79 79]/255;
            
            % Real Obstacle 1 - Rectangle
            if margin ~= 0
                rectangle(app.UIAxes, 'Position', app.shape{1} + margin*[-1 -1 2 2], 'FaceColor', color, 'EdgeColor', color);
            end
            rectangle(app.UIAxes, 'Position', app.shape{1}, 'FaceColor', 'k', 'EdgeColor', 'k');
            
            % Real Obstacle 2 - Concave Shape
            if margin ~= 0
                concShape = [app.shape{2}; app.shape{2}(1,:)];
                p = [];
                for i = 1:6
                    p = [p; polyfit(concShape(i:i+1,1), concShape(i:i+1,2), 1)];
                end
                cDiff = margin*sqrt(1 + p(:,1).^2);
                p(:,2) = p(:,2) + cDiff.*[1 1 1 -1 -1 -1]';
                p = [p; p(1,:)];
                x = (p(2:end,2) - p(1:end-1,2))./(p(1:end-1,1) - p(2:end,1));
                y = p(1:end-1,1).*x + p(1:end-1,2);
                concShape = [x, y];
                patch(app.UIAxes, concShape(:,1), concShape(:,2), color, 'EdgeColor', color);
            end
            patch(app.UIAxes, app.shape{2}(:,1), app.shape{2}(:,2), 'k', 'EdgeColor', 'k');
            
            % Real Obstacle 3 - Circle
            if margin ~= 0
                rectangle(app.UIAxes, 'Position', app.shape{3} + margin*[-1 -1 2 2], 'Curvature', [1 1], 'FaceColor', color, 'EdgeColor', color);
            end
            rectangle(app.UIAxes, 'Position', app.shape{3}, 'Curvature', [1 1], 'FaceColor', 'k', 'EdgeColor', 'k');
            
            % Real Obstacle 4 - Ellipse
            if ~isempty(app.shape{4})
                if margin ~= 0
                    rectangle(app.UIAxes, 'Position', app.shape{4} + margin*[-1 -1 2 2], 'Curvature', [1 1], 'FaceColor', color, 'EdgeColor', color);
                end
                rectangle(app.UIAxes, 'Position', app.shape{4}, 'Curvature', [1 1], 'FaceColor', 'k', 'EdgeColor', 'k');
            end
            
            % Boundary
            if margin ~= 0
                % Top Boundary
                rectangle(app.UIAxes, 'Position', [0 150-margin 250 margin], 'FaceColor', color, 'EdgeColor', color);
                % Right Boundary
                rectangle(app.UIAxes, 'Position', [250-margin 0 margin 150], 'FaceColor', color, 'EdgeColor', color);
                % Bottom Boundary
                rectangle(app.UIAxes, 'Position', [0 0 250 margin], 'FaceColor', color, 'EdgeColor', color);
                % Left Boundary
                rectangle(app.UIAxes, 'Position', [0 0 margin 150], 'FaceColor', color, 'EdgeColor', color);
            end
            
            % Switch hold off
            hold(app.UIAxes, 'off');
        end
        
        %%%% Toggle GUI objects - settings
        function toggleSettings(app, value)
            % Input 1 - Search Algorithm
            app.SearchAlgorithmDropDownLabel.Enable = value;
            app.SearchAlgorithmDropDown.Enable = value;
            
            % Input 2 - Weights
            if strcmp(app.searchAlgo, 'Weighted A*')
                app.WeightSpinnerLabel.Enable = value;
                app.WeightSpinner.Enable = value;
            end

            % Input 3 - Action Space
            app.ActionSpaceDropDownLabel.Enable = value;
            app.ActionSpaceDropDown.Enable = value;
            
            % Input 4 - Distance
            app.DistanceDropDownLabel.Enable = value;
            app.DistanceDropDown.Enable = value;
            
            % Input 5 - Map
            app.MapDropDownLabel.Enable = value;
            app.MapDropDown.Enable = value;
            
            % Input 6 - Resolution
            app.ResolutionSpinnerLabel.Enable = value;
            app.ResolutionSpinner.Enable = value;
            
            % Input 7 - Robot Type
            app.RobotTypeDropDownLabel.Enable = value;
            app.RobotTypeDropDown.Enable = value;
            
            % Input 8 - Robot Radius
            if strcmp(app.RobotTypeDropDown.Value, 'Rigid')
                app.RobotRadiusSpinnerLabel.Enable = value;
                app.RobotRadiusSpinner.Enable = value;
            end
            
            % Input 9 - Clearance
            if strcmp(app.RobotTypeDropDown.Value, 'Rigid')
                app.ClearanceSpinnerLabel.Enable = value;
                app.ClearanceSpinner.Enable = value;
            end
        end
        
        %%%% Toggle GUI objects - positions
        function togglePositions(app, value)
            % Input 1 - Start Position X
            app.XLabel_Start.Enable = value;
            app.XEditField_Start.Enable = value;
            
            % Input 2 - Start Position Y
            app.YLabel_Start.Enable = value;
            app.YEditField_Start.Enable = value;
            
            % Input 3 - Goal Position X
            app.XLabel_Goal.Enable = value;
            app.XEditField_Goal.Enable = value;
            
            % Input 4 - Goal Position Y
            app.YLabel_Goal.Enable = value;
            app.YEditField_Goal.Enable = value;
        end
        
        %%%% Generate unique number - 2 inputs
        function num = nodeNumberGrid(~, X, Y)
            num = (2.^X).*(3.^Y);
        end
        
        %%%% Generate unique number - single input
        function num = nodeNumber(~, pos)
            num = (2.^(pos(:,1))).*(3.^(pos(:,2)));
        end
        
        %%%% Plot the grid map
        function plotGridMap(app)
            % Clear the axes
            cla(app.UIAxes);
            
            % Show the grid
            if app.res >= 5
                grid(app.UIAxes, 'on')
            end
            
            % Define the boundary margin
            margin = app.radius + app.clearance;
            
            % Switch hold on
            hold(app.UIAxes, 'on');
            
            % Rectangle
            rect = [app.shape{1}(1:2) - margin, app.shape{1}(1:2) + app.shape{1}(3:4) + margin];
            obs{1} = ((app.map{2} <= rect(4)) | (app.map{2} - (app.res/2) < rect(4))) & ...   % top
                     ((app.map{1} <= rect(3)) | (app.map{1} - (app.res/2) < rect(3))) & ...   % right
                     ((app.map{2} >= rect(2)) | (app.map{2} + (app.res/2) > rect(2))) & ...   % bottom
                     ((app.map{1} >= rect(1)) | (app.map{1} + (app.res/2) > rect(1)));        % left
            
            % Concave Polygon
            concShape = [app.shape{2}; app.shape{2}(1,:)];
            p = [];
            for i = [3:6, 1:2]
                p = [p; polyfit(concShape(i:i+1,1), concShape(i:i+1,2), 1)];
            end
            cDiff = margin*sqrt(1 + p(:,1).^2);
            p(:,2) = p(:,2) + cDiff.*[1 -1 -1 -1 1 1]';
            obs{2} = ((app.map{2} < p(1,1)*app.map{1} + p(1,2)) | (app.map{2} - (app.res/2) < p(1,1)*(app.map{1} - (app.res/2)) + p(1,2))) & ...
                     ((app.map{2} > p(2,1)*app.map{1} + p(2,2)) | (app.map{2} + (app.res/2) > p(2,1)*(app.map{1} - (app.res/2)) + p(2,2))) & ...
                     ((app.map{2} > p(3,1)*app.map{1} + p(3,2)) | (app.map{2} + (app.res/2) > p(3,1)*app.map{1} + p(3,2))) & ...
                     ((app.map{2} > p(4,1)*app.map{1} + p(4,2)) | (app.map{2} + (app.res/2) > p(4,1)*(app.map{1} + (app.res/2)) + p(4,2))) & ...
                     (((app.map{2} < p(5,1)*app.map{1} + p(5,2)) | (app.map{2} - (app.res/2) < p(5,1)*(app.map{1} - (app.res/2)) + p(5,2))) | ...
                      ((app.map{2} < p(6,1)*app.map{1} + p(6,2)) | (app.map{2} - (app.res/2) < p(6,1)*(app.map{1} + (app.res/2)) + p(6,2))));
            p = [p; p(1,:)];
            x = (p(2:end,2) - p(1:end-1,2))./(p(1:end-1,1) - p(2:end,1));
            y = p(1:end-1,1).*x + p(1:end-1,2);
            concShape = [x, y; x(1), y(1)];
            obs{2} = obs{2} & ((app.map{2} <= concShape(6,2)) | (app.map{2} - (app.res/2) < concShape(6,2))) & ...  % Topmost edge
                              ((app.map{1} <= concShape(1,1)) | (app.map{1} - (app.res/2) < concShape(1,1))) & ...  % Rightmost edge
                              ((app.map{2} >= concShape(2,2)) | (app.map{2} - (app.res/2) > concShape(2,2))) & ...  % Bottommost edge
                              ((app.map{1} >= concShape(4,1)) | (app.map{1} + (app.res/2) > concShape(4,1)));       % Leftmost edge
                              
            
            % Circle
            circ = [app.shape{3}(1:2) + (app.shape{3}(3:4)/2), (app.shape{3}(3)/2) + margin];
            obs{3} = ((app.map{1} - circ(1)).^2 + (app.map{2} - circ(2)).^2 <= circ(3)^2) | ...
                     ((app.map{1} + (app.res/2) - circ(1)).^2 + (app.map{2} + (app.res/2) - circ(2)).^2 < circ(3)^2) | ...
                     ((app.map{1} + (app.res/2) - circ(1)).^2 + (app.map{2} - (app.res/2) - circ(2)).^2 < circ(3)^2) | ...
                     ((app.map{1} - (app.res/2) - circ(1)).^2 + (app.map{2} - (app.res/2) - circ(2)).^2 < circ(3)^2) | ...
                     ((app.map{1} - (app.res/2) - circ(1)).^2 + (app.map{2} + (app.res/2) - circ(2)).^2 < circ(3)^2);
            
            % Ellipse
            if ~isempty(app.shape{4})
                elli = [app.shape{4}(1:2) + (app.shape{4}(3:4)/2), (app.shape{4}(3:4)/2) + margin];
                obs{4} = ((((app.map{1} - elli(1)).^2)/elli(3)^2) + (((app.map{2} - elli(2)).^2)/elli(4)^2) <= 1) | ...
                         ((((app.map{1} + (app.res/2) - elli(1)).^2)/elli(3)^2) + (((app.map{2} + (app.res/2) - elli(2)).^2)/elli(4)^2) < 1) | ...
                         ((((app.map{1} + (app.res/2) - elli(1)).^2)/elli(3)^2) + (((app.map{2} - (app.res/2) - elli(2)).^2)/elli(4)^2) < 1) | ...
                         ((((app.map{1} - (app.res/2) - elli(1)).^2)/elli(3)^2) + (((app.map{2} - (app.res/2) - elli(2)).^2)/elli(4)^2) < 1) | ...
                         ((((app.map{1} - (app.res/2) - elli(1)).^2)/elli(3)^2) + (((app.map{2} + (app.res/2) - elli(2)).^2)/elli(4)^2) < 1);
            else
                obs{4} = zeros(size(app.map{3}));
            end
            
            % Boundary
            if margin ~= 0
                boundary = [150 - margin, 250 - margin, margin, margin];
                obs{5} = ((app.map{2} >= boundary(1)) | (app.map{2} + (app.res/2) > boundary(1))) | ...   % top
                         ((app.map{1} >= boundary(2)) | (app.map{1} + (app.res/2) > boundary(2))) | ...   % right
                         ((app.map{2} <= boundary(3)) | (app.map{2} - (app.res/2) < boundary(3))) | ...   % bottom
                         ((app.map{1} <= boundary(4)) | (app.map{1} - (app.res/2) < boundary(4)));        % left
            else
                obs{5} = zeros(size(app.map{3}));
            end
            
            % All obstacles
            O = obs{1} | obs{2} | obs{3} | obs{4} | obs{5};
            
            % Find the obstacles
            ind = find(O);
            x = app.map{1}(ind);
            y = app.map{2}(ind);
            num = app.map{3}(ind);
            
            % Store the obstacle space
            remove(app.obsSpace, keys(app.obsSpace));
            for i = 1:numel(ind)
                app.obsSpace(num(i)) = [x(i) y(i)];
                rectangle(app.UIAxes, 'Position', [x(i)-(app.res/2) y(i)-(app.res/2) app.res app.res], 'FaceColor', 'k');
            end
            
            % Plot the actual/modified obstacles
            rectangle(app.UIAxes, 'Position', app.shape{1} + margin*[-1 -1 2 2], 'EdgeColor', 'm');
            line(app.UIAxes, concShape(:,1), concShape(:,2), 'Color', 'm');
            rectangle(app.UIAxes, 'Position', app.shape{3} + margin*[-1 -1 2 2], 'Curvature', [1 1], 'EdgeColor', 'm');
            if ~isempty(app.shape{4})
                rectangle(app.UIAxes, 'Position', app.shape{4} + margin*[-1 -1 2 2], 'Curvature', [1 1], 'EdgeColor', 'm');
            end
            
            % Switch hold off
            hold(app.UIAxes, 'off');
        end
        
        %%%% Get grid position for given coordinates
        function gridPos = world2grid(app, worldPos)
            % Initialize possible x and y positions
            X = app.map{1}(1,:);
            Y = app.map{2}(:,1);
            
            % Start conversion
            for i = 1:size(worldPos, 1)
                % Locate x grid position
                x_grid = find(worldPos(i,1) <= X, 1);
                if isempty(x_grid)
                    x_grid = numel(X);
                elseif worldPos(i,1) + (app.res/2) < X(x_grid)
                    x_grid = x_grid - 1;
                end
                
                % Locate y grid position
                y_grid = find(worldPos(i,2) <= Y, 1);
                if isempty(y_grid)
                    y_grid = numel(Y);
                elseif worldPos(i,2) + (app.res/2) < Y(y_grid)
                    y_grid = y_grid - 1;
                end
                
                % Define the point
                gridPos(i,:) = [X(x_grid), Y(y_grid)];
            end
        end
        
        %%%% Compute the cost to come
        function dist = computeDistance(app, p1, p2)
            if strcmp(app.searchAlgo, 'BFS') || strcmp(app.searchAlgo, 'DFS') || strcmp(app.searchAlgo, 'Dijkstra')
                dist = 0;
            elseif strcmp(app.searchAlgo, 'A*') || strcmp(app.searchAlgo, 'Weighted A*')
                if strcmp(app.distance, 'Manhattan')
                    dist = app.w * sum(abs(p1 - p2));
                elseif strcmp(app.distance, 'Euclidean')
                    dist = app.w * norm(p1 - p2);
                elseif strcmp(app.distance, 'Diagonal')
                    dist = app.w * max(abs(p1 - p2));
                end
            end
        end
        
        %%%% The planning algorithm
        function pathPlanning(app)
            % Set the initial node
            init_node.pos = app.startPos;
            init_node.gcost = 0;
            init_node.hcost = computeDistance(app, app.startPos, app.goalPos);
            init_node.fcost = init_node.gcost + init_node.hcost;
            init_node.parent = struct;
            
            % Start an OPEN list
            OPEN = containers.Map(init_node.fcost, init_node);
            costQueue = init_node.fcost;
            
            % Define all possible actions
            app.actions = app.res * app.actions;
            
            % Start a CLOSED list
            CLOSED = containers.Map('KeyType', 'double', 'ValueType', 'any');
            if strcmp(app.searchAlgo, 'BFS') || strcmp(app.searchAlgo, 'DFS')
                CLOSED(nodeNumber(app, init_node.pos)) = init_node;
            end
            
            % Start the node list
            app.nodes = app.startPos;
            
            % Start a path list
            app.path = app.goalPos;
            tol = app.res/1e4;
            
            % Switch hold on
            pause on
            hold(app.UIAxes, 'on');
            
            % Search algorithm
            while ~isempty(OPEN)
                % Select the minimum or maximum cost depending on the search algorithm
                if strcmp(app.searchAlgo, 'BFS') || strcmp(app.searchAlgo, 'Dijkstra') || ...
                   strcmp(app.searchAlgo, 'A*') || strcmp(app.searchAlgo, 'Weighted A*')
                    [currCost, ind] = min(costQueue);
                elseif strcmp(app.searchAlgo, 'DFS')
                    [currCost, ind] = max(costQueue);
                end
                
                % Select one element if there are multiple candidates
                if length(OPEN(currCost)) > 1
                    OPEN_temp = OPEN(currCost);
                    current_node = OPEN_temp(1);
%                     OPEN_temp(1:end-1) = OPEN_temp(2:end);
                    OPEN_temp(1) = [];
                    OPEN(currCost) = OPEN_temp;
                else
                    current_node = OPEN(currCost);
                    remove(OPEN, currCost);
                    costQueue(ind) = [];
%                     costQueue = costQueue(abs(costQueue - currCost) > tol);
                end
                
                % Check if goal is reached
                if all(abs(current_node.pos - app.goalPos) < tol)
                    parent_node = current_node.parent;
                    while any(abs(parent_node.pos - app.startPos) > tol)
                        app.path = [app.path; parent_node.pos];
                        parent_node = parent_node.parent;
                    end
                    break;
                end
                
                % Check if the current node has already been expanded
                if strcmp(app.searchAlgo, 'Dijkstra') || strcmp(app.searchAlgo, 'A*') || strcmp(app.searchAlgo, 'Weighted A*')
                    if isKey(CLOSED, nodeNumber(app, current_node.pos))
                        continue;
                    end
                    CLOSED(nodeNumber(app, current_node.pos)) = current_node;
                end
                
                % Expand the current node
                for i = 1:size(app.actions, 1)
                    neighbor_node.pos = current_node.pos + app.actions(i,:);
                    
                    % Check if the new node is a valid node
                    if any(neighbor_node.pos < 0) || any(neighbor_node.pos > [250 150]) || ... % if it lies outside the boundary
                       isKey(app.obsSpace, nodeNumber(app, neighbor_node.pos)) || ...          % if it lies inside the obstacle space
                       isKey(CLOSED, nodeNumber(app, neighbor_node.pos))                       % if it already has been expanded/stored
                        continue;
                    end
                    
                    % Define all parameters for this node
                    if strcmp(app.searchAlgo, 'BFS') || strcmp(app.searchAlgo, 'DFS')
                        neighbor_node.gcost = current_node.gcost + app.res;
                    elseif strcmp(app.searchAlgo, 'Dijkstra') || strcmp(app.searchAlgo, 'A*') || strcmp(app.searchAlgo, 'Weighted A*')
                        neighbor_node.gcost = current_node.gcost + norm(neighbor_node.pos - current_node.pos);
                    end
                    neighbor_node.hcost = computeDistance(app, neighbor_node.pos, app.goalPos);
                    neighbor_node.fcost = neighbor_node.gcost + neighbor_node.hcost;
                    neighbor_node.parent = current_node;
                    
                    % Add the node to the open list
                    if isKey(OPEN, neighbor_node.fcost)
                        OPEN(neighbor_node.fcost) = [OPEN(neighbor_node.fcost); neighbor_node];
                    else
                        OPEN(neighbor_node.fcost) = neighbor_node;
                        costQueue(end+1) = neighbor_node.fcost;
                    end
                    
                    % Add the node to the closed list
                    if strcmp(app.searchAlgo, 'BFS') || strcmp(app.searchAlgo, 'DFS')
                        CLOSED(nodeNumber(app, neighbor_node.pos)) = neighbor_node;
                    end
                end
                
                % Plot the node expanded
                if any(abs(current_node.pos - app.startPos) > tol)
                    app.nodes = [app.nodes; current_node.pos];
                    rectangle(app.UIAxes, 'Position', [current_node.pos - (app.res/2), app.res, app.res], 'FaceColor', 'y');
                    pause(0.1)
                end
            end
            
            % Add the last elements to the path and nodes list
            app.path = [app.path; app.startPos];
            app.path = flip(app.path);
            app.nodes = [app.nodes; app.goalPos];
            
            % Plot path, if it exists
            if all(abs(current_node.pos - app.goalPos) < tol)
                app.CommentsTextArea.Value = {'A path has been found from the given start position to goal position.'};
                for i = 2:(size(app.path,1)-1)
                    rectangle(app.UIAxes, 'Position', [app.path(i,:) - (app.res/2), app.res, app.res], 'FaceColor', 'c');
                    pause(0.1)
                end
                plot(app.UIAxes, app.path(:,1), app.path(:,2))
            else
                app.path = [];
                app.CommentsTextArea.Value = {'No path could be found from the given start position to goal position.'};
                pause(2)
            end
            
            % Switch hold off
            hold(app.UIAxes, 'off');
            pause off
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Place the GUI in the center of the screen
            screenSize = get(0,'ScreenSize');
            edge = (screenSize(3:4) - app.UIFigure.Position(3:4))/2;
            edge(edge < 0) = 0;
            app.UIFigure.Position = [edge app.UIFigure.Position(3:4)];
            drawnow;
            
            % Plot the map
            app.UIAxes.XTick = 0:app.res:250;
            app.UIAxes.YTick = 0:app.res:150;
            xtickangle(app.UIAxes, 90);
            app.shape = {[55 67.5 50 45]; [120 55; 158 51; 165 89; 188 51; 168 14; 145 14]; [165 105 30 30]; []};
            plotMap(app);
        end

        % Value changed function: SearchAlgorithmDropDown
        function SearchAlgorithmDropDownValueChanged(app, event)
            app.searchAlgo = app.SearchAlgorithmDropDown.Value;
            
            % Check if weights are required
            if strcmp(app.searchAlgo, 'Weighted A*')
                app.WeightSpinnerLabel.Enable = 'on';
                app.WeightSpinner.Enable = 'on';
            else
                app.WeightSpinnerLabel.Enable = 'off';
                app.WeightSpinner.Enable = 'off';
                app.w = 1;
                app.WeightSpinner.Value = app.w;
            end
        end

        % Value changed function: ActionSpaceDropDown
        function ActionSpaceDropDownValueChanged(app, event)
            selectedObject = app.ActionSpaceDropDown.Value;
            
            % Define the actions matrix as per the action space chosen
            if strcmp(selectedObject, '4-connected')
                app.actions = [0 1; 1 0; 0 -1; -1 0];
            elseif strcmp(selectedObject, '8-connected')
                app.actions = [0 1; 1 1; 1 0; 1 -1; 0 -1; -1 -1; -1 0; -1 1];
            end
        end

        % Value changed function: DistanceDropDown
        function DistanceDropDownValueChanged(app, event)
            app.distance = app.DistanceDropDown.Value;
        end

        % Value changed function: MapDropDown
        function MapDropDownValueChanged(app, event)
            app.mapName = app.MapDropDown.Value;
            
            % Set the values for shapes
            if strcmp(app.mapName, 'Map 2018')
                % Real Obstacle 1 - Rectangle
                app.shape{1} = [55 67.5 50 45];
                % Real Obstacle 2 - Concave Shape
                app.shape{2} = [120 55; 158 51; 165 89; 188 51; 168 14; 145 14];
                % Real Obstacle 3 - Circle
                app.shape{3} = [165 105 30 30];
                % No fourth obstacle
                app.shape{4} = [];
            elseif strcmp(app.mapName, 'Map 2019')
                % Real Obstacle 1 - Rectangle
                app.shape{1} = [50 67.5 50 45];
                % Real Obstacle 2 - Concave Shape
                app.shape{2} = [125 56; 163 52; 170 90; 193 52; 173 15; 150 15];
                % Real Obstacle 3 - Circle
                app.shape{3} = [175 115 30 30];
                % Real Obstacle 4 - Ellipse
                app.shape{4} = [125 114 30 12];
            end
            
            % Plot the updated map
            plotMap(app);
        end

        % Value changed function: ResolutionSpinner
        function ResolutionSpinnerValueChanged(app, event)
            value = app.ResolutionSpinner.Value;
            
            pause on
            % Check if the resolution is feasible
            if (rem(250, value) ~= 0) || (rem(150, value) ~= 0)
                app.ResolutionSpinner.Enable = 'off';
                app.ResolutionSpinner.Value = app.res;
                app.CommentsTextArea.Value = {'Uniform Grid cannot be formed for the given resolution.'};
                pause(2)
                app.ResolutionSpinner.Enable = 'on';
            else
                app.res = value;
            end
            app.CommentsTextArea.Value = {'Select all the parameters and then move on to select the start and goal positions.'};
            pause off
            
            % Update the grid size
            if app.res >= 5
                app.UIAxes.XTick = 0:app.res:250;
                app.UIAxes.YTick = 0:app.res:150;
            end
        end

        % Value changed function: RobotTypeDropDown
        function RobotTypeDropDownValueChanged(app, event)
            selectedObject = app.RobotTypeDropDown.Value;
            
            % Check if robot radius and clearance are required
            if strcmp(selectedObject, 'Point')
                app.RobotRadiusSpinner.Enable = 'off';
                app.RobotRadiusSpinnerLabel.Enable = 'off';
                app.ClearanceSpinner.Enable = 'off';
                app.ClearanceSpinnerLabel.Enable = 'off';
                app.radius = 0;
                app.clearance = 0;
                app.RobotRadiusSpinner.Value = app.radius;
                app.ClearanceSpinner.Value = app.clearance;
            elseif strcmp(selectedObject, 'Rigid')
                app.RobotRadiusSpinner.Enable = 'on';
                app.RobotRadiusSpinnerLabel.Enable = 'on';
                app.ClearanceSpinner.Enable = 'on';
                app.ClearanceSpinnerLabel.Enable = 'on';
                app.radius = 5;
                app.clearance = 2;
                app.RobotRadiusSpinner.Value = app.radius;
                app.ClearanceSpinner.Value = app.clearance;
            end
            
            % Plot the updated map
            plotMap(app);
        end

        % Value changed function: RobotRadiusSpinner
        function RobotRadiusSpinnerValueChanged(app, event)
            app.radius = app.RobotRadiusSpinner.Value;
            
            % Plot the updated map
            plotMap(app);
        end

        % Value changed function: ClearanceSpinner
        function ClearanceSpinnerValueChanged(app, event)
            app.clearance = app.ClearanceSpinner.Value;
            
            % Plot the updated map
            plotMap(app);
        end

        % Button pushed function: SetPositionsButton
        function SetPositionsButtonPushed(app, event)
            % Change the button visibility
            app.SetPositionsButton.Visible = 'off';
            
            % Toggle the GUI objects
            toggleSettings(app, 'off');
            
            % Create Grid Map
            x = 0+(app.res/2):app.res:250-(app.res/2);
            y = 0+(app.res/2):app.res:150-(app.res/2);
            [app.map{1}, app.map{2}] = meshgrid(x, y);
            app.map{3} = nodeNumberGrid(app, app.map{1}, app.map{2});
            
            % Plot the grid map
            pause on
            app.CommentsTextArea.Value = {'Creating Grid Map.'};
            pause(0.1)
            plotGridMap(app)
            pause off
            
            % Convert start and goal position to grid positions
            gridPos = world2grid(app, [app.startPos; app.goalPos]);
            
            % Find the first free space
            if isKey(app.obsSpace, nodeNumber(app, gridPos(1,:)))
                for i = 1:numel(app.map{3})
                    if ~isKey(app.obsSpace, app.map{3}(i))
                        app.startPos = [app.map{1}(i), app.map{2}(i)];
                        app.XEditField_Start.Value = app.startPos(1);
                        app.YEditField_Start.Value = app.startPos(2);
                        break;
                    end
                end
            end
            
            % Find the last free space
            if isKey(app.obsSpace, nodeNumber(app, gridPos(2,:)))
                for i = numel(app.map{3}):-1:1
                    if ~isKey(app.obsSpace, app.map{3}(i))
                        app.goalPos = [app.map{1}(i), app.map{2}(i)];
                        app.XEditField_Goal.Value = app.goalPos(1);
                        app.YEditField_Goal.Value = app.goalPos(2);
                        break;
                    end
                end
            end
            
            % Change the text in comments box
            app.CommentsTextArea.Value = {'Select the positions and then start the path planning algorithm. You can go back to modify the parameters.'};
            
            % Change the button visibility
            app.StartButton.Visible = 'on';
            app.BackButton.Visible = 'on';
            
            % Toggle the GUI objects
            togglePositions(app, 'on');
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            % Change the button visibility
            app.StartButton.Visible = 'off';
            app.BackButton.Visible = 'off';
            
            % Toggle the GUI objects
            togglePositions(app, 'off');
            
            % Plot the actual map
            pause on
            app.CommentsTextArea.Value = {'Creating Actual Map.'};
            pause(0.1)
            plotMap(app)
            pause off
            
            % Change the text in comments box
            app.CommentsTextArea.Value = {'Select all the parameters and then move on to select the start and goal positions.'};
            
            % Change the button visibility
            app.SetPositionsButton.Visible = 'on';
            
            % Toggle the GUI objects
            toggleSettings(app, 'on');
        end

        % Value changed function: XEditField_Start
        function XEditField_StartValueChanged(app, event)
            app.startPos(1) = app.XEditField_Start.Value;
        end

        % Value changed function: YEditField_Start
        function YEditField_StartValueChanged(app, event)
            app.startPos(2) = app.YEditField_Start.Value;
        end

        % Value changed function: XEditField_Goal
        function XEditField_GoalValueChanged(app, event)
            app.goalPos(1) = app.XEditField_Goal.Value;
        end

        % Value changed function: YEditField_Goal
        function YEditField_GoalValueChanged(app, event)
            app.goalPos(2) = app.YEditField_Goal.Value;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Convert start and goal position to grid positions
            gridPos = world2grid(app, [app.startPos; app.goalPos]);
            
            % Check if start position is in the free space
            if ~isKey(app.obsSpace, nodeNumber(app, gridPos(1,:)))
                app.startPos = gridPos(1,:);
            else
                app.CommentsTextArea.Value = {'Start position is not in the free space. Try again or go back to modify the parameters.'};
                return;
            end
            
            % Check if goal position is in the free space
            if ~isKey(app.obsSpace, nodeNumber(app, gridPos(2,:)))
                app.goalPos = gridPos(2,:);
            else
                app.CommentsTextArea.Value = {'Goal position is not in the free space. Try again or go back to modify the parameters.'};
                return;
            end
            
            % Plot the start and goal positions
            rectangle(app.UIAxes, 'Position', [app.startPos - (app.res/2), app.res, app.res], 'FaceColor', 'r');
            rectangle(app.UIAxes, 'Position', [app.goalPos - (app.res/2), app.res, app.res], 'FaceColor', 'b');
            
            % Change the button visibility
            app.StartButton.Visible = 'off';
            app.BackButton.Visible = 'off';
            
            % Toggle the GUI objects
            togglePositions(app, 'off');
            
            % Start the Planning Algorithm
            app.CommentsTextArea.Value = {['Starting Path Planning using ' app.searchAlgo]};
            pathPlanning(app);
            
            % Change the button visibility
            app.ReplayButton.Visible = 'on';
            app.ResetButton.Visible = 'on';
            app.CloseButton.Visible = 'on';
            
            % Change the text in comments box
            app.CommentsTextArea.Value = {'Either replay the animation, reset the app, or close the app.'};
        end

        % Value changed function: WeightSpinner
        function WeightSpinnerValueChanged(app, event)
            app.w = app.WeightSpinner.Value;
        end

        % Value changing function: ResolutionSpinner
        function ResolutionSpinnerValueChanging(app, event)
            value = app.ResolutionSpinner.Value;
            app.CommentsTextArea.Value = {'Resolution value is being set.'};
            
            % Check if the value is still changing
            pause on
            pause(0.5)
            while value ~= app.ResolutionSpinner.Value
                value = app.ResolutionSpinner.Value;
                pause(0.5)
            end
            pause off
            
            % Check if the resolution is feasible
            ResolutionSpinnerValueChanged(app, event)
        end

        % Button pushed function: ReplayButton
        function ReplayButtonPushed(app, event)
            % Change the text in comments box
            app.CommentsTextArea.Value = {'Replaying the animation.'};
            
            % Change the button visibility
            app.ReplayButton.Visible = 'off';
            app.ResetButton.Visible = 'off';
            app.CloseButton.Visible = 'off';
            
            % Plot the grid map
            plotGridMap(app)
            
            % Switch hold on
            hold(app.UIAxes, 'on');
            pause on
            
            % Plot the start and goal positions
            rectangle(app.UIAxes, 'Position', [app.startPos - (app.res/2), app.res, app.res], 'FaceColor', 'r');
            rectangle(app.UIAxes, 'Position', [app.goalPos - (app.res/2), app.res, app.res], 'FaceColor', 'b');
            
            % Set tolerance
            tol = app.res/1e4;
            
            % Plot the nodes expanded
            for i = 1:length(app.nodes)
                if any(abs(app.nodes(i,:) - app.startPos) > tol) && any(abs(app.nodes(i,:) - app.goalPos) > tol)
                    rectangle(app.UIAxes, 'Position', [app.nodes(i,:) - (app.res/2), app.res, app.res], 'FaceColor', 'y');
                    pause(0.001)
                end
            end
            
            % Plot path, if it exists
            if ~isempty(app.path)
                for i = 2:(size(app.path,1)-1)
                    rectangle(app.UIAxes, 'Position', [app.path(i,:) - (app.res/2), app.res, app.res], 'FaceColor', 'c');
                    pause(0.01)
                end
                plot(app.UIAxes, app.path(:,1), app.path(:,2))
            end
            
            % Switch hold off
            hold(app.UIAxes, 'off');
            pause off
            
            % Change the text in comments box
            app.CommentsTextArea.Value = {'Either replay the animation, reset the app, or close the app.'};
            
            % Change the button visibility
            app.ReplayButton.Visible = 'on';
            app.ResetButton.Visible = 'on';
            app.CloseButton.Visible = 'on';
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Reset all properties
            app.goalPos = [250 150];
            app.map = {};
            app.nodes = [];
            remove(app.obsSpace, keys(app.obsSpace));
            app.path = [];
            app.res = 5;
            app.startPos = [0 0];
            
            % Reset UIAxes settings
            app.UIAxes.XTick = 0:app.res:250;
            app.UIAxes.YTick = 0:app.res:150;
            
            % Reset the GUI
            app.SearchAlgorithmDropDown.Value = 'BFS';
            SearchAlgorithmDropDownValueChanged(app, event);
            app.ActionSpaceDropDown.Value = '4-connected';
            ActionSpaceDropDownValueChanged(app, event);
            app.DistanceDropDown.Value = 'Manhattan';
            DistanceDropDownValueChanged(app, event);
            app.MapDropDown.Value = 'Map 2018';
            MapDropDownValueChanged(app, event);
            app.ResolutionSpinner.Value = app.res;
            ResolutionSpinnerValueChanged(app, event);
            app.RobotTypeDropDown.Value = 'Point';
            RobotTypeDropDownValueChanged(app, event);
            app.XEditField_Start.Value = app.startPos(1);
            app.YEditField_Start.Value = app.startPos(2);
            app.XEditField_Goal.Value = app.goalPos(1);
            app.YEditField_Goal.Value = app.goalPos(2);
            
            % Change the text in comments box
            app.CommentsTextArea.Value = {'Select all the parameters and then move on to select the start and goal positions.'};
            
            % Change the button visibility
            app.ReplayButton.Visible = 'off';
            app.ResetButton.Visible = 'off';
            app.CloseButton.Visible = 'off';
            app.SetPositionsButton.Visible = 'on';
            toggleSettings(app, 'on');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SearchAlgorithmDropDownLabel = uilabel(app.UIFigure);
            app.SearchAlgorithmDropDownLabel.Text = 'Search Algorithm';
            app.SearchAlgorithmDropDown = uidropdown(app.UIFigure);
            app.SearchAlgorithmDropDown.Items = {'BFS', 'DFS', 'Dijkstra', 'A*', 'Weighted A*'};
            app.SearchAlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @SearchAlgorithmDropDownValueChanged, true);
            app.SearchAlgorithmDropDown.Value = 'BFS';
            app.ResolutionSpinnerLabel = uilabel(app.UIFigure);
            app.ResolutionSpinnerLabel.Text = 'Resolution';
            app.ResolutionSpinner = uispinner(app.UIFigure);
            app.ResolutionSpinner.ValueChangingFcn = createCallbackFcn(app, @ResolutionSpinnerValueChanging, true);
            app.ResolutionSpinner.Limits = [0 Inf];
            app.ResolutionSpinner.ValueChangedFcn = createCallbackFcn(app, @ResolutionSpinnerValueChanged, true);
            app.ResolutionSpinner.Value = 5;
            app.MapDropDownLabel = uilabel(app.UIFigure);
            app.MapDropDownLabel.Text = 'Map';
            app.MapDropDown = uidropdown(app.UIFigure);
            app.MapDropDown.Items = {'Map 2018', 'Map 2019'};
            app.MapDropDown.ValueChangedFcn = createCallbackFcn(app, @MapDropDownValueChanged, true);
            app.MapDropDown.Value = 'Map 2018';
            app.StartPositionPanel = uipanel(app.UIFigure);
            app.StartPositionPanel.Title = 'Start Position';
            app.XLabel_Start = uilabel(app.StartPositionPanel);
            app.XLabel_Start.Enable = 'off';
            app.XLabel_Start.Text = 'X :';
            app.XEditField_Start = uieditfield(app.StartPositionPanel, 'numeric');
            app.XEditField_Start.Limits = [0 250];
            app.XEditField_Start.ValueChangedFcn = createCallbackFcn(app, @XEditField_StartValueChanged, true);
            app.XEditField_Start.Enable = 'off';
            app.YLabel_Start = uilabel(app.StartPositionPanel);
            app.YLabel_Start.Enable = 'off';
            app.YLabel_Start.Text = 'Y :';
            app.YEditField_Start = uieditfield(app.StartPositionPanel, 'numeric');
            app.YEditField_Start.Limits = [0 150];
            app.YEditField_Start.ValueChangedFcn = createCallbackFcn(app, @YEditField_StartValueChanged, true);
            app.YEditField_Start.Enable = 'off';
            app.GoalPositionPanel = uipanel(app.UIFigure);
            app.GoalPositionPanel.Title = 'Goal Position';
            app.XLabel_Goal = uilabel(app.GoalPositionPanel);
            app.XLabel_Goal.Enable = 'off';
            app.XLabel_Goal.Text = 'X :';
            app.XEditField_Goal = uieditfield(app.GoalPositionPanel, 'numeric');
            app.XEditField_Goal.Limits = [0 250];
            app.XEditField_Goal.ValueChangedFcn = createCallbackFcn(app, @XEditField_GoalValueChanged, true);
            app.XEditField_Goal.Enable = 'off';
            app.XEditField_Goal.Value = 250;
            app.YLabel_Goal = uilabel(app.GoalPositionPanel);
            app.YLabel_Goal.Enable = 'off';
            app.YLabel_Goal.Text = 'Y :';
            app.YEditField_Goal = uieditfield(app.GoalPositionPanel, 'numeric');
            app.YEditField_Goal.Limits = [0 150];
            app.YEditField_Goal.ValueChangedFcn = createCallbackFcn(app, @YEditField_GoalValueChanged, true);
            app.YEditField_Goal.Enable = 'off';
            app.YEditField_Goal.Value = 150;
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X (in mm)')
            ylabel(app.UIAxes, 'Y (in mm)')
            app.UIAxes.XLim = [0 250];
            app.UIAxes.YLim = [0 150];
            app.UIAxes.XTick = [0 50 100 150 200 250];
            app.UIAxes.YTick = [0 50 100 150];
            app.SetPositionsButton = uibutton(app.UIFigure, 'push');
            app.SetPositionsButton.ButtonPushedFcn = createCallbackFcn(app, @SetPositionsButtonPushed, true);
            app.SetPositionsButton.Text = 'Set the Start and Goal Positions';
            app.ActionSpaceDropDownLabel = uilabel(app.UIFigure);
            app.ActionSpaceDropDownLabel.Text = 'Action Space';
            app.ActionSpaceDropDown = uidropdown(app.UIFigure);
            app.ActionSpaceDropDown.Items = {'4-connected', '8-connected'};
            app.ActionSpaceDropDown.ValueChangedFcn = createCallbackFcn(app, @ActionSpaceDropDownValueChanged, true);
            app.ActionSpaceDropDown.Value = '4-connected';
            app.DistanceDropDownLabel = uilabel(app.UIFigure);
            app.DistanceDropDownLabel.Text = 'Distance';
            app.DistanceDropDown = uidropdown(app.UIFigure);
            app.DistanceDropDown.Items = {'Manhattan', 'Euclidean', 'Diagonal'};
            app.DistanceDropDown.ValueChangedFcn = createCallbackFcn(app, @DistanceDropDownValueChanged, true);
            app.DistanceDropDown.Value = 'Manhattan';
            app.CommentsTextAreaLabel = uilabel(app.UIFigure);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.CommentsTextArea = uitextarea(app.UIFigure);
            app.CommentsTextArea.Editable = 'off';
            app.CommentsTextArea.Value = {'Select all the parameters and then move on to select the start and goal positions.'};
            app.RobotTypeDropDownLabel = uilabel(app.UIFigure);
            app.RobotTypeDropDownLabel.Text = 'Robot Type';
            app.RobotTypeDropDown = uidropdown(app.UIFigure);
            app.RobotTypeDropDown.Items = {'Point', 'Rigid'};
            app.RobotTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @RobotTypeDropDownValueChanged, true);
            app.RobotTypeDropDown.Value = 'Point';
            app.RobotRadiusSpinnerLabel = uilabel(app.UIFigure);
            app.RobotRadiusSpinnerLabel.Enable = 'off';
            app.RobotRadiusSpinnerLabel.Text = 'Robot Radius';
            app.RobotRadiusSpinner = uispinner(app.UIFigure);
            app.RobotRadiusSpinner.Limits = [0 Inf];
            app.RobotRadiusSpinner.ValueChangedFcn = createCallbackFcn(app, @RobotRadiusSpinnerValueChanged, true);
            app.RobotRadiusSpinner.Enable = 'off';
            app.ClearanceSpinnerLabel = uilabel(app.UIFigure);
            app.ClearanceSpinnerLabel.Enable = 'off';
            app.ClearanceSpinnerLabel.Text = 'Clearance';
            app.ClearanceSpinner = uispinner(app.UIFigure);
            app.ClearanceSpinner.Limits = [0 Inf];
            app.ClearanceSpinner.ValueChangedFcn = createCallbackFcn(app, @ClearanceSpinnerValueChanged, true);
            app.ClearanceSpinner.Enable = 'off';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Visible = 'off';
            app.StartButton.Text = 'Start';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Visible = 'off';
            app.BackButton.Text = 'Back';
            app.WeightSpinnerLabel = uilabel(app.UIFigure);
            app.WeightSpinnerLabel.Enable = 'off';
            app.WeightSpinnerLabel.Text = 'Weight';
            app.WeightSpinner = uispinner(app.UIFigure);
            app.WeightSpinner.Limits = [0 Inf];
            app.WeightSpinner.ValueChangedFcn = createCallbackFcn(app, @WeightSpinnerValueChanged, true);
            app.WeightSpinner.Enable = 'off';
            app.WeightSpinner.Value = 1;
            app.ReplayButton = uibutton(app.UIFigure, 'push');
            app.ReplayButton.ButtonPushedFcn = createCallbackFcn(app, @ReplayButtonPushed, true);
            app.ReplayButton.Visible = 'off';
            app.ReplayButton.Text = 'Replay';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Visible = 'off';
            app.ResetButton.Text = 'Reset';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Visible = 'off';
            app.CloseButton.Text = 'Close';
        end
    end

    methods (Access = public)

        % Construct app
        function app = searchAlgorithm

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end